classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SingleVolumeModeTab            matlab.ui.container.Tab
        FittingDistributionDropDownLabel  matlab.ui.control.Label
        FittingDistributionDropDown    matlab.ui.control.DropDown
        LevelSetIterationEditFieldLabel  matlab.ui.control.Label
        LevelSetIterationEditField     matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        PDETimeStepEditFieldLabel      matlab.ui.control.Label
        PDETimeStepEditField           matlab.ui.control.NumericEditField
        SmoothSigmaEditFieldLabel      matlab.ui.control.Label
        SmoothSigmaEditField           matlab.ui.control.NumericEditField
        ClusterRadiusEditFieldLabel    matlab.ui.control.Label
        ClusterRadiusEditField         matlab.ui.control.NumericEditField
        FileModeTab                    matlab.ui.container.Tab
        FittingDistributionDropDown_2Label  matlab.ui.control.Label
        FittingDistributionDropDown_2  matlab.ui.control.DropDown
        LevelSetIterationEditField_2Label  matlab.ui.control.Label
        LevelSetIterationEditField_2   matlab.ui.control.NumericEditField
        RunButton_2                    matlab.ui.control.Button
        StopButton_2                   matlab.ui.control.Button
        PDETimeStepEditField_2Label    matlab.ui.control.Label
        PDETimeStepEditField_2         matlab.ui.control.NumericEditField
        SmoothSigmaEditField_2Label    matlab.ui.control.Label
        SmoothSigmaEditField_2         matlab.ui.control.NumericEditField
        ClusterRadiusEditField_2Label  matlab.ui.control.Label
        ClusterRadiusEditField_2       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopButton, StopButton_2
        function StopButtonPushed(app, event)
            close(app.UIFigure);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            addpath('levelset_utils');
            addpath('curvature_utils');
            %addpath('new_function');
            
            iter_num = app.LevelSetIterationEditField.Value; % iteration of the global distribution levelset

            pdf_case = app.FittingDistributionDropDown.Value; % specified foreground distribution 
            
            PDE_step = app.PDETimeStepEditField.Value; % specific pde time step
            
            sigma = app.SmoothSigmaEditField.Value; % specific smooth sigma
            
            radium = app.ClusterRadiusEditField.Value; % specific cluster radium
            
            [filename, pathname] = uigetfile('.nii', 'Choose NII File');
            volume_name = [pathname, filename];
            split_name = strsplit(volume_name, '.');
            segmentation_name = [split_name{1}, '_#seg.nii'];
            detection_name = [split_name{1}, '_#det.nii'];
            vol = niftiread(volume_name);
            
            t0 = clock;
            fprintf('%s begins.\n', volume_name);
            seg_result = LevelSetSeg(vol, pdf_case, iter_num, PDE_step);
            raw_seg = seg_result;
            seg_result = SepJoint(seg_result);
            se = strel('sphere', 1);
            seg_result = single(imclose(seg_result, se));
            ConReg_vol = MaxConRegion(seg_result);
            ConReg_vol = MaxConFill(ConReg_vol);
            ConReg_vol = SurfaceShrink(ConReg_vol, raw_seg, 7, 0.4, 1, 0, 1, 0.5, 5);
            phi = SignDistance(ConReg_vol);
            phi = LSEdgeSmooth(phi, ConReg_vol, 0.04, 1, 3, 2, 5, 5);
            ConReg_vol = single(phi > 0);
            niftiwrite(ConReg_vol, segmentation_name);
            t1 = clock;
            fprintf('Time consuming for %s segmentation: %.3f.\n', volume_name, etime(t1, t0));
            mask = ErosionDetection(ConReg_vol, 5, 5);               
            result_vol = RefineEro(mask, ConReg_vol);
            l = length(unique(result_vol))-1;
            fprintf('%d erosions detected in %s.\n', l, split_name{1});            
            t2 = clock;
            fprintf('Time comsuming for %s detection: %.3f.\n', volume_name, etime(t2, t1));
            %niftiwrite(seg_result, save_name);
            niftiwrite(result_vol, detection_name);
            fprintf('Volume has been processed! \n');
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            addpath('levelset_utils');
            addpath('curvature_utils');
            %addpath('new_function');
            
            ratio_thresh = 12;
            num_thresh = 120;
            
            input_path = uigetdir;
            seg_path = [input_path, '_seg/'];
            det_path = [input_path, '_det/'];
            input_path = [input_path, '/'];
            if ~exist(seg_path, 'dir')
                mkdir(seg_path);
            end
            if ~exist(det_path, 'dir')
                mkdir(det_path);
            end


            pdf_case = app.FittingDistributionDropDown_2.Value;
            iter_num = app.LevelSetIterationEditField_2.Value;
            pde_step = app.PDETimeStepEditField_2.Value;
            sigma = app.SmoothSigmaEditField_2.Value;
            radium = app.ClusterRadiusEditField_2.Value;
            
            im_dir = dir(fullfile(input_path, '*.nii'));
            im_num = length(im_dir);
            if im_num == 0
                error(disp('Empty directory \n !'));
            end
            for i = 1 : im_num
                t0 = clock;
                name = im_dir(i).name;
                split_name = strsplit(name, '.');
                seg_name = [seg_path, split_name{1}, '_#seg.nii'];
                det_name = [det_path, split_name{1}, '_#det.nii'];
                %det_file_path = [det_path, split_name{1}, '_det/'];
                %up_seg_name = [up_seg_path, split_name{1}, '_#upseg.nii'];
                fprintf('#### No.%d (%s) under processing...\n', i, name);
                fprintf('\n');
                vol = single(niftiread(fullfile(input_path, name)));
                [vx, vy, vz] = size(vol);
                seg_result = LevelSetSeg(vol, pdf_case, iter_num, pde_step);
                raw_seg = seg_result;
                seg_result = SepJoint(seg_result);
                se = strel('sphere', 1);
                seg_result = single(imclose(seg_result, se));
                ConReg_vol = MaxConRegion(seg_result);
                ConReg_vol = MaxConFill(ConReg_vol);
                ConReg_vol = SurfaceShrink(ConReg_vol, raw_seg, 7, 0.4, 1, 0, 1, 0.5, 5);
                phi = SignDistance(ConReg_vol);
                phi = LSEdgeSmooth(phi, ConReg_vol, 0.04, 1, 3, 2, 5, 5);
                ConReg_vol = single(phi > 0);
                niftiwrite(ConReg_vol, seg_name);
                t1 = clock;
                fprintf('Time consuming for %s segmentation: %.3f.\n', name, etime(t1, t0));
                mask = ErosionDetection(ConReg_vol, 4, 5);               
                result_vol = RefineEro(mask, ConReg_vol);
                l = length(unique(result_vol))-1;
                fprintf('%d erosions detected in %s.\n', l, name);
                t2 = clock;
                fprintf('Time comsuming for %s detection: %.3f.\n', name, etime(t2, t1));
                niftiwrite(result_vol, det_name);
                fprintf('\n');             
            end
            fprintf('All the volumes have been processed! \n');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleVolumeModeTab = uitab(app.TabGroup);
            app.SingleVolumeModeTab.Title = 'Single-Volume Mode';
            app.FittingDistributionDropDownLabel = uilabel(app.SingleVolumeModeTab);
            app.FittingDistributionDropDownLabel.Text = 'Fitting Distribution';
            app.FittingDistributionDropDown = uidropdown(app.SingleVolumeModeTab);
            app.FittingDistributionDropDown.Items = {'Gamma', 'Lognormal', 'Weibutll', 'Rayleigh', 'Gauss'};
            app.FittingDistributionDropDown.Value = 'Gamma';
            app.LevelSetIterationEditFieldLabel = uilabel(app.SingleVolumeModeTab);
            app.LevelSetIterationEditFieldLabel.Text = 'Level Set Iteration';
            app.LevelSetIterationEditField = uieditfield(app.SingleVolumeModeTab, 'numeric');
            app.LevelSetIterationEditField.Limits = [1 100];
            app.LevelSetIterationEditField.Value = 15;
            app.RunButton = uibutton(app.SingleVolumeModeTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.SingleVolumeModeTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PDETimeStepEditFieldLabel = uilabel(app.SingleVolumeModeTab);
            app.PDETimeStepEditFieldLabel.Text = 'PDE Time Step';
            app.PDETimeStepEditField = uieditfield(app.SingleVolumeModeTab, 'numeric');
            app.PDETimeStepEditField.Value = 6;
            app.SmoothSigmaEditFieldLabel = uilabel(app.SingleVolumeModeTab);
            app.SmoothSigmaEditFieldLabel.Text = 'Smooth Sigma';
            app.SmoothSigmaEditField = uieditfield(app.SingleVolumeModeTab, 'numeric');
            app.SmoothSigmaEditField.Value = 4;
            app.ClusterRadiusEditFieldLabel = uilabel(app.SingleVolumeModeTab);
            app.ClusterRadiusEditFieldLabel.Text = 'Cluster Radius ';
            app.ClusterRadiusEditField = uieditfield(app.SingleVolumeModeTab, 'numeric');
            app.ClusterRadiusEditField.Value = 5;
            app.FileModeTab = uitab(app.TabGroup);
            app.FileModeTab.Title = 'File Mode';
            app.FittingDistributionDropDown_2Label = uilabel(app.FileModeTab);
            app.FittingDistributionDropDown_2Label.Text = 'Fitting Distribution';
            app.FittingDistributionDropDown_2 = uidropdown(app.FileModeTab);
            app.FittingDistributionDropDown_2.Items = {'Gamma', 'Lognormal', 'Weibutll', 'Rayleigh', 'Gauss'};
            app.FittingDistributionDropDown_2.Value = 'Gamma';
            app.LevelSetIterationEditField_2Label = uilabel(app.FileModeTab);
            app.LevelSetIterationEditField_2Label.Text = 'Level Set Iteration';
            app.LevelSetIterationEditField_2 = uieditfield(app.FileModeTab, 'numeric');
            app.LevelSetIterationEditField_2.Limits = [1 100];
            app.LevelSetIterationEditField_2.Value = 15;
            app.RunButton_2 = uibutton(app.FileModeTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.StopButton_2 = uibutton(app.FileModeTab, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton_2.Text = 'Stop';
            app.PDETimeStepEditField_2Label = uilabel(app.FileModeTab);
            app.PDETimeStepEditField_2Label.Text = 'PDE Time Step';
            app.PDETimeStepEditField_2 = uieditfield(app.FileModeTab, 'numeric');
            app.PDETimeStepEditField_2.Value = 6;
            app.SmoothSigmaEditField_2Label = uilabel(app.FileModeTab);
            app.SmoothSigmaEditField_2Label.Text = 'Smooth Sigma';
            app.SmoothSigmaEditField_2 = uieditfield(app.FileModeTab, 'numeric');
            app.SmoothSigmaEditField_2.Value = 4;
            app.ClusterRadiusEditField_2Label = uilabel(app.FileModeTab);
            app.ClusterRadiusEditField_2Label.Text = 'Cluster Radius';
            app.ClusterRadiusEditField_2 = uieditfield(app.FileModeTab, 'numeric');
            app.ClusterRadiusEditField_2.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end